classdef CSVtoTimePts < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        ExitMenu                       matlab.ui.container.Menu
        readyLampLabel                 matlab.ui.control.Label
        readyLamp                      matlab.ui.control.Lamp
        SelectFilesLabel               matlab.ui.control.Label
        SelectFilesButton              matlab.ui.control.Button
        FindtimepointsButton           matlab.ui.control.Button
        MarkerFileNameEditFieldLabel   matlab.ui.control.Label
        MarkerFileNameEditField        matlab.ui.control.EditField
        SystemStartTimeEditFieldLabel  matlab.ui.control.Label
        SystemStartTimeEditField       matlab.ui.control.NumericEditField
        NumberofMarkersEditFieldLabel  matlab.ui.control.Label
        NumberofMarkersEditField       matlab.ui.control.NumericEditField
        SelectGroupDropDownLabel       matlab.ui.control.Label
        SelectGroupDropDown            matlab.ui.control.DropDown
    end


    properties (Access = private)
        BVP_p = table();
        HR_p = table();
        TEMP_p = table();
        EDA_p = table();
        
        BVP_c = table();
        HR_c = table();
        TEMP_c = table();
        EDA_c = table();
        
        number_markers = 1;
        group = '';
        thresholds = struct();
        start_time = 0;
        is_ready = 0; % Description
    end

    methods (Access = private)
    
        function r_start_time = get_start_time(app,type)
            r_start_time = app.start_time;
            
        end
        function end_time = get_end_time(app,type)
            if(strcmp(type,"p"))
                hr_start = str2num(app.HR_p{end,1}{1});
                temp_start = str2num(app.TEMP_p{end,1}{1});
                eda_start = str2num(app.EDA_p{end,1}{1});
                end_time = max([hr_start,temp_start,eda_start]);
            else
                hr_start = str2num(app.HR_c{end,1}{1});
                temp_start = str2num(app.TEMP_c{end,1}{1});
                eda_start = str2num(app.EDA_c{end,1}{1});
                end_time = max([hr_start,temp_start,eda_start]);
            end 
       end
    
        function [averaged_array,time_array] = get_average(app,type_part,type,win_size,start,stop)
            
            if(strcmp(type_part,"p"))
               hr = app.HR_p;
               temp = app.TEMP_p;
               eda = app.EDA_p;
            else
               hr = app.HR_c;
               temp = app.TEMP_c;
               eda = app.EDA_c;
            end
            data = [];
            time = [];
            
            if(strcmp(type,'hr'))
                time = hr{1:end,1};
                data = hr{1:end,2};
            elseif(strcmp(type,'temp'))
                time = temp{1:end,1};
                data = temp{1:end,2};                   
            elseif(strcmp(type,'eda'))
                time = eda{1:end,1};
                data = eda{1:end,2};
            end
            
            max_index = size(time,1);
            current_index = 1;
            arr_ind = 1;

            assignin('base','start',start);
            assignin('base','stop',stop);
            assignin('base','win_size',win_size);            
            for threshold = start:win_size:stop-win_size
                total_size = 0;
                total_data = 0;
                current_time = str2num(time{current_index,1});
                
                while( current_time < (threshold+win_size) && (current_index < max_index))
                    total_data = total_data + str2num(data{current_index,1});
                    total_size = total_size + 1;
                    current_index = current_index + 1;
                    current_time = str2num(time{current_index,1});
                end
                if(total_size > 0)
                    averaged_data = total_data/total_size;
                    averaged_array(arr_ind,1) = averaged_data;
                    time_array(arr_ind,1) = threshold+(win_size/2);
                    arr_ind = arr_ind + 1;
                end
            end
            
        end
    
        function return_events = find_events(app,type)
            r_start_time = get_start_time(app,type);
            end_time = get_end_time(app,type);    
            win_size = 500;
            [hr_averaged,hr_time] = get_average(app,type,'hr',win_size,r_start_time,end_time);
            [temp_averaged,temp_time] = get_average(app,type,'temp',win_size,r_start_time,end_time);
            [eda_averaged,eda_time] = get_average(app,type,'eda',win_size,r_start_time,end_time);
            
            if(strcmp(type,'p'))
                curr_thresh = app.thresholds.p;
            elseif(strcmp(type,'c'))
                curr_thresh = app.thresholds.c;
            end
            
            events = struct();
            events.eda.time = [];
            events.eda.diff = [];
            events.hr.time = [];
            events.hr.diff = [];
            events.temp.time = [];
            events.temp.diff = [];

            %Heart rate exceeds 120 bmp
            %Heart rate drops below 50 bmp
            for i=1:size(hr_time,1)
               is_hr_high = hr_averaged(i,1) > curr_thresh.hr(1,2);
               is_hr_low = hr_averaged(i,1) < curr_thresh.hr(1,1);
               if(is_hr_high || is_hr_low)
                   events.hr.time = [events.hr.time ; (hr_time(i,1) -r_start_time)/1000];
                   if(is_hr_low)
                        hr_change = abs(hr_averaged(i,1)-curr_thresh.hr(1,1));                     
                   elseif(is_hr_high)
                        hr_change = hr_averaged(i,1)-curr_thresh.hr(1,2);
                   end
                   events.hr.diff = [events.hr.diff; hr_change];
               end
            end
            
            %There is an increase in greater than 0.1 uV over the course of 5 seconds in the EDA
            offset = 5/0.5;
            for i = 1:(size(eda_time,1)-offset)
                eda_change = eda_averaged(i+offset,1) - eda_averaged(i,1) ;
                if(eda_change > curr_thresh.eda)
                   events.eda.time = [events.eda.time; (eda_time(floor(i+offset/2),1)-r_start_time)/1000]; 
                   events.eda.diff = [events.eda.diff; eda_change/curr_thresh.eda];
                end
            end
            %There is an increase or decrease in skin temperature of greater than 0.5 degrees Celcius over the course of 10 seconds
            offset = 10/0.5;
            for i = 1:(size(temp_time,1)-offset)
               temp_change = abs(temp_averaged(i,1) - temp_averaged(i+offset,1));
               if(temp_change > curr_thresh.temp)
                   events.temp.time = [events.temp.time;(temp_time(floor(i+offset/2),1)-r_start_time)/1000]
                   events.temp.diff = [events.temp.diff; temp_change/curr_thresh.temp];
               end
            end
            
            %Here should find the most important events and make a blueprint of the quantity of events
            eda_events_num = size(events.eda.time,1);
            hr_events_num = size(events.hr.time,1);
            temp_events_num = size(events.temp.time,1);
            fileID = fopen([app.MarkerFileNameEditField.Value,'.log'],'a');
            fprintf(fileID,'Date : %s\n',date);
            fprintf(fileID,'Type : %s\n',type);
            fprintf(fileID,'EDA EVENTS: %d | HR EVENTS: %d | TEMP EVENTS: %d\n',eda_events_num,hr_events_num,temp_events_num);
            
            for i = 1:size(events.eda.time,1)
                mod = 'eda';
                fprintf(fileID,'mod: %s, time: %d, diff: %d \n',mod,events.eda.time(i,1),events.eda.diff(i,1));
            end
            for i = 1:size(events.hr.time,1)
                mod = 'hr';
                fprintf(fileID,'mod: %s, time: %d, diff: %d \n',mod,events.hr.time(i,1),events.hr.diff(i,1));
            end
            for i = 1:size(events.temp.time,1)
                mod = 'temp';
                fprintf(fileID,'mod: %s, time: "%d", diff: "%d" \n',mod,events.temp.time(i,1),events.temp.diff(i,1));
            end
            fprintf(fileID,'---------------------------------------\n');
            fclose(fileID);
            
            
            %Make a log of the full events and the differences associated with each of them
            return_events = [];
            for i =1:app.number_markers
                [max_eda,ind_eda] = max(events.eda.diff);
                [max_hr,ind_hr] = max(events.hr.diff);
                [max_temp,ind_temp] = max(events.temp.diff);
                if(isempty(max_eda))
                   max_eda = -1; 
                end
                
                if(isempty(max_hr))
                   max_hr = -1;
                end
                
                if(isempty(max_temp))
                   max_temp = -1;
                end
                
                if((max_eda >= max_hr) && (max_eda >= max_temp))
                    return_events = [return_events ; events.eda.time(ind_eda,1)];
                    display(['EDA : ', num2str(events.eda.diff(ind_eda,1))]);
                    events.eda.diff(ind_eda,1) = -1;
                elseif((max_hr >= max_eda) && (max_hr >= max_temp))
                    return_events = [return_events ; events.hr.time(ind_hr,1)]
                    display(['HR : ', num2str(events.hr.diff(ind_eda,1))]);;
                    events.hr.diff(ind_hr,1) = -1;                    
                elseif((max_temp >= max_eda) && (max_temp >= max_hr))
                    return_events = [return_events ; events.temp.time(ind_temp,1)];
                    display(['TEMP : ', num2str(events.temp.diff(ind_eda,1))]);
                    events.temp.diff(ind_temp,1) = -1;                    
                end
                
            end
            return_events = sort(return_events);
        end
        
        function save_events(app,events,type)
            file_name = app.MarkerFileNameEditField.Value;
            file_name = strcat(file_name,".json");
            events = unique(int32(events));
            fileID = fopen(file_name,'a');
            if(strcmp(type,"p"))
               class = "marker-red";
               fprintf(fileID,'[\n');
            else
               class = "marker-blue";                
            end
            for i = 1:size(events,1)
                if(i ~= size(events,1) || strcmp(type,"p"))
                    fprintf(fileID,'{"time": %d, "text": "%s%d", "class": "%s"},\n',events(i),type,i,class);
                else
                    fprintf(fileID,'{"time": %d, "text": "%s%d", "class": "%s"}\n',events(i),type,i,class);
                end
            end
            if(strcmp(type,"c"))
                fprintf(fileID,']\n');
            end
            fclose(fileID);
        end
        
        function [bvp_score,sc_score,temp_score] = get_sqi(app,type_part)
            if(strcmp(type_part,"p"))
               bvp = app.BVP_p;
               temp = app.TEMP_p;
               eda = app.EDA_p;
            else
               bvp = app.BVP_c;
               temp = app.TEMP_c;
               eda = app.EDA_c;
            end

            bvp_mat = str2double(bvp{1:end,2});
            temp_mat = str2double(temp{1:end,2});                   
            eda_mat = str2double(eda{1:end,2});    
                    
            [bvp_score,sc_score,temp_score] = comp_SQI_3(bvp_mat,eda_mat,temp_mat);
        end
        
        function save_sqi(app,type,bvp_score,eda_score,temp_score)
            file_name = app.MarkerFileNameEditField.Value;
            file_name = strcat(file_name,'.sqi');
            fileID = fopen(file_name,'a');
            fprintf(fileID,'type: %s \nbvp score: %d , eda score: %d and temp score: %d\n',type,bvp_score,eda_score,temp_score);
            fclose(fileID);
        end
        
    end


    methods (Access = private)

        % Button pushed function: SelectFilesButton
        function SelectFilesButtonPushed(app, event)
            path = uigetdir;
            if isequal(path,0)
                disp('User selected Cancel');
                return;
            else
                disp(['User selected folder: ', path]);
                listing = dir(path);
                row_offset = 1;
                col_offset = 0;
                for i = 1:size(listing)
                    file_name = listing(i).name;
                    full_path = strcat(path,'\',file_name);
                                        
                    if(contains(file_name, 'p_bvp'))
                        disp(['File : ',file_name,' is bvp p']);          
                        app.BVP_p =readtable(full_path);
                        assignin('base','BVP_p',app.BVP_p);
                    elseif(contains(file_name, 'p_hr'))
                        disp(['File : ',file_name,' is HR p']);          
                        app.HR_p =readtable(full_path);
                        assignin('base','HR_p',app.HR_p);
                    elseif(contains(file_name, 'p_temp'))
                        disp(['File : ',file_name,' is TEMP p']);        
                        app.TEMP_p =readtable(full_path);
                        assignin('base','TEMP_p',app.TEMP_p);
                    elseif(contains(file_name, 'p_eda'))
                        disp(['File : ',file_name,' is EDA p']);
                        app.EDA_p =readtable(full_path);
                        assignin('base','EDA_p',app.EDA_p);
                    elseif(contains(file_name, 'c_bvp'))
                        disp(['File : ',file_name,' is bvp c']);          
                        app.BVP_c =readtable(full_path);
                        assignin('base','BVP_c',app.BVP_c);                        
                    elseif(contains(file_name, 'c_hr'))
                        disp(['File : ',file_name,' is HR c']);
                        app.HR_c =readtable(full_path);
                        assignin('base','HR_c',app.HR_c);
                    elseif(contains(file_name, 'c_temp'))
                        disp(['File : ',file_name,' is TEMP c']);
                        app.TEMP_c =readtable(full_path);
                        assignin('base','TEMP_c',app.TEMP_c);
                    elseif(contains(file_name, 'c_eda'))
                        disp(['File : ',file_name,' is EDA c']);
                        app.EDA_c =readtable(full_path);
                        assignin('base','EDA_c',app.EDA_c);                        
                    end
                    
                    app.readyLamp.Color = [0,1,0];
                    app.is_ready = 1;
                end
            end 
        end

        % Button pushed function: FindtimepointsButton
        function FindtimepointsButtonPushed(app, event)
            app.start_time = app.SystemStartTimeEditField.Value;
            if(app.is_ready && app.start_time > 0)    
                clc;
                display('Program Launched');
                display('Loading variables...');
                app.number_markers = app.NumberofMarkersEditField.Value;
                app.group = app.SelectGroupDropDown.Value;
                temp_data = load('Group.mat');
                if(strcmp(app.group,'Blue'))
                    app.thresholds = temp_data.Group.blue;
                elseif(strcmp(app.group,'Green'))
                    app.thresholds = temp_data.Group.green;                    
                elseif(strcmp(app.group,'Orange'))
                    app.thresholds = temp_data.Group.orange;                    
                elseif(strcmp(app.group,'Red'))
                    app.thresholds = temp_data.Group.red;                    
                elseif(strcmp(app.group,'Yellow'))
                    app.thresholds = temp_data.Group.yellow;                    
                end
                
                display('Finding events for participants...');
                events_p = find_events(app,"p");
                display('Finding events for care-giver...');
                events_c = find_events(app,"c");
                display('Calculating signal quality for participants...')
                [bvp_score_p,eda_score_p,temp_score_p] = get_sqi(app,"p");
                display('Saving signal quality for participants...');
                save_sqi(app,"p",bvp_score_p,eda_score_p,temp_score_p);  
                display('Calculating signal quality for care-giver...')
                [bvp_score_c,eda_score_c,temp_score_c] = get_sqi(app,"c");  
                display('Saving signal quality for care-giver...');
                save_sqi(app,"c",bvp_score_c,eda_score_c,temp_score_c);
                display('Save events for participants...');
                save_events(app,events_p,"p");
                display('Save events for care-giver...');
                save_events(app,events_c,"c");  
                display('Program Terminated!');
                app.EDA_p = [];
                app.TEMP_p = [];
                app.HR_p = [];
                
                app.EDA_c = [];
                app.TEMP_c = [];
                app.HR_c = [];                
                app.readyLamp.Color = [1,0,0];
                app.is_ready = 0;
            else
                display('Select a folder first and make sure start time > 0');
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.Text = 'Exit';
            app.readyLampLabel = uilabel(app.UIFigure);
            app.readyLampLabel.Text = 'ready';
            app.readyLamp = uilamp(app.UIFigure);
            app.SelectFilesLabel = uilabel(app.UIFigure);
            app.SelectFilesLabel.Text = 'Select Folder where all four sensor data are located in CSV format.';
            app.SelectFilesButton = uibutton(app.UIFigure, 'push');
            app.SelectFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFilesButtonPushed, true);
            app.SelectFilesButton.Text = 'Select Files';
            app.FindtimepointsButton = uibutton(app.UIFigure, 'push');
            app.FindtimepointsButton.ButtonPushedFcn = createCallbackFcn(app, @FindtimepointsButtonPushed, true);
            app.FindtimepointsButton.Text = 'Find time points';
            app.MarkerFileNameEditFieldLabel = uilabel(app.UIFigure);
            app.MarkerFileNameEditFieldLabel.Text = 'Marker File Name';
            app.MarkerFileNameEditField = uieditfield(app.UIFigure, 'text');
            app.SystemStartTimeEditFieldLabel = uilabel(app.UIFigure);
            app.SystemStartTimeEditFieldLabel.Text = 'System Start Time';
            app.SystemStartTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.SystemStartTimeEditField.Limits = [0 Inf];
            app.NumberofMarkersEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofMarkersEditFieldLabel.Text = 'Number of Markers';
            app.NumberofMarkersEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofMarkersEditField.Limits = [1 Inf];
            app.NumberofMarkersEditField.Value = 1;
            app.SelectGroupDropDownLabel = uilabel(app.UIFigure);
            app.SelectGroupDropDownLabel.Text = 'Select Group';
            app.SelectGroupDropDown = uidropdown(app.UIFigure);
            app.SelectGroupDropDown.Items = {'Blue', 'Green', 'Orange', 'Red', 'Yellow'};
            app.SelectGroupDropDown.Value = 'Blue';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CSVtoTimePts

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end